classdef PhotodegradationAnalysisapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel_3                   matlab.ui.container.Panel
        Panel_5                   matlab.ui.container.Panel
        ExportLabel               matlab.ui.control.Label
        ExportPlotButton          matlab.ui.control.Button
        LineofBestFitLabel        matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
        Panel_2                   matlab.ui.container.Panel
        Panel_4                   matlab.ui.container.Panel
        WavelengthEditField       matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel  matlab.ui.control.Label
        GraphButton               matlab.ui.control.Button
        LocalMaximumButton        matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        Panel                     matlab.ui.container.Panel
        LoadDataButton            matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
    end

    
    properties (Access = private)
        % Data Collection and Storage
        filename % Stores all filenames in cell
        pathname % Stores files paths
        numfile % Number of files
        spectra % Cell of all numerical and text data from each file
        Xdata % Stores all X data (wavelength)
        Ydata % Stores all Y daata (absorbtion)

        % UI collected data
        wlmin % Determines lower range of data wavelength
        wlmax % Determines upper range of data wavelength
        plt % Saves each plot
        ROILine % Line on plot1 that shows selected wavelength
        gauss
        scat % Holds data of each scatter plot
        relabs % Holds ln of relative absorbtion
        lobf % line of best fit
        lgn % legend
    end

    
    methods (Access = private)
        
        function PltSetUp(app)
             app.plt = cell(1, app.numfile);
             for k = 1:app.numfile
                 app.plt{k} = plot(app.UIAxes, app.Xdata{k}, app.Ydata{k});          %Create Plot of all vals
                 hold(app.UIAxes, 'on');
             end
             app.lgn = legend(app.UIAxes);
%              app.UIAxes.Legend = zeros(1, app.numfile);
%              app.plt.Color
%              s = uistyle("BackgroundColor", [app.plt.Color])
%              addStyle(app.UITable, s, "column", 1)
             hold(app.UIAxes, 'off')
             app.ROILine = images.roi.Line(app.UIAxes, "Position", [app.wlmin, app.UIAxes.YLim(1)-1; app.wlmin, app.UIAxes.YLim(2)*1.01], "Deletable",false,"InteractionsAllowed","Translate",'Color', [0 0 0]);
             addlistener(app.ROILine, "ROIMoved", @(src,event)ROIChangedCallback(app,event));
        end

        function ROIChangedCallback(app, event)
            switch event.EventName  % This appears unneccesary, but errors occured w/o
                case 'ROIMoved'
                    app.WavelengthEditField.Value = app.ROILine.Position(1);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Cell edit callback: UITable
        function TableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2) == 1
                if newData == 0 
                    % Hides plots when unchecked
                    app.plt{indices(1)}.Visible = false;
                    app.plt{indices(1)}.HandleVisibility = "off";
                else
                    app.plt{indices(1)}.Visible = true;
                    app.plt{indices(1)}.HandleVisibility = "on"; 
                    app.lgn.String(indices(1)) = app.UITable.DisplayData(indices(1),3);
                end
            end
            if indices(2) == 3
                app.lgn.String(indices(1)) = {newData};
            end
        end

        % Value changed function: WavelengthEditField
        function EditField(app, event)
            value = app.WavelengthEditField.Value;
            % Move line with input
            app.ROILine.Position(1) = value;
            app.ROILine.Position(2) = value;
        end

        % Button pushed function: LocalMaximumButton
        function Maximum(app, event)
            index = find(strcmp(app.UITable.DisplayData(:,3), '0'));
            if (length(index) ~= 1)
                uialert(app.UIFigure, 'Enter one timepoint as zero', 'Error')
                return;
            end

            % Check Matlab version, throw error if prior to r2020b
            assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')
        
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;            % Current status
            app.UIFigure.HandleVisibility = 'callback';    % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)           % Make fig current
        
            
            temp = app.Label.Text;                          % Change user instructions
            app.Label.Visible = 'off';
            app.Label.Text = 'You are now selecting your x peak bounds.';
            app.Label.Visible = 'on';                 % Give info to user
            [XPeakGuess,~,~] = ginput(2);    % run ginput
            app.Label.Text = temp;
        
            app.UIFigure.HandleVisibility = fhv;            % return original state
        
            minFitBound = min(XPeakGuess);
            maxFitBound = max(XPeakGuess);
            
            xRange = app.Xdata{index}(minFitBound <= app.Xdata{index});
            xRange = xRange(maxFitBound >= xRange);
            yRange = app.Ydata{index}(minFitBound <= app.Xdata{index});
            yRange = yRange(maxFitBound >= xRange);
        
            app.gauss = fit(xRange, yRange, 'gauss1');         % Using time zero data
            opt = fitoptions(app.gauss);
            opt.Lower(2) = minFitBound;
            opt.Upper(2) = maxFitBound;
            opt.StartPoint(2) = (maxFitBound - minFitBound)/2;
            app.WavelengthEditField.Value = app.gauss.b1;
            app.ROILine.Position(1) = app.WavelengthEditField.Value;
            app.ROILine.Position(2) = app.WavelengthEditField.Value;
        end

        % Button pushed function: GraphButton
        function lnGraph(app, event)
            cla(app.UIAxes2)
            app.UIAxes2.Visible = "on";
            app.ExportPlotButton.Visible = "on";
            wl = app.WavelengthEditField.Value;
            index = find(strcmp(app.UITable.DisplayData(:,3), '0')); % cell index of timepoint zero

            % Alert if timepoint of zero entered incorrectly
            if (length(index) ~= 1)
                uialert(app.UIFigure, 'Enter exactly ONE timepoint as zero', 'Error')
                return;
            end

        %   % Alert if timepoints are not full
        %   if nonzeros(relabs) ~= find(cell2mat(wltbl.DisplayData(:, 1)))
        %       uialert(fig, 'Fill out timepoint for all checked values', 'Error')
        %       return;
        %   end
            [~, idx] = min(abs(app.Xdata{index}-wl));           % Find closest index to wavelength value
            j = find(cell2mat(app.UITable.Data(:,1)));          % Find Checked Rows
            app.relabs = zeros(1, length(j));                         % ln(Abs / Abs@time=0)
     
            
            for k = 1:app.numfile      % Find Checked Rows 
                app.relabs(k) =  reallog((app.Ydata{k}(idx))/(app.Ydata{index}(idx)));
            end

            tp = str2double(app.UITable.Data(:,3));

            scatter(app.UIAxes2, tp(j), app.relabs(j)', 'filled');            % Create scatter plot of  ln(Abs / Abs@time=0) vs time

            hold(app.UIAxes2, 'on');
            app.lobf = polyfit(tp(j),app.relabs(j),1);                               % Find line of best fit
            plot(app.UIAxes2, tp, polyval(app.lobf,tp));
            app.LineofBestFitLabel.Text = join(["Line of best Fit:    ", app.lobf(1), 'x  +  ', app.lobf(2)]);
            app.LineofBestFitLabel.Visible = "on";
            hold (app.UIAxes2, 'off')
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            if exist('pathname','var') == 0
                [app.filename,app.pathname] = uigetfile('*.xlsx','Select OceanOptics data file(s) to import.','MultiSelect', 'on');
            else
                [app.filename,app.pathname] = uigetfile([app.pathname '*.xlsx'],'Select OceanOptics data file(s) to import.','MultiSelect', 'on');
            end
            
            if ischar(app.filename) == 1
                  app.filename = cellstr(app.filename);
            end
            app.numfile = length(app.filename);

            app.spectra = cell(1,app.numfile);        % Preallocate/Initialize cell arrays
            app.Xdata = cell(1,app.numfile);
            app.Ydata = cell(1,app.numfile);
            
            for k=1:app.numfile
                 app.spectra{k} = importdata(append(app.pathname, app.filename{k}));
                 app.Xdata{k} = app.spectra{k}.data(:,1);
                 app.Ydata{k} = app.spectra{k}.data(:,2);
            end
            app.UITable.Data = [mat2cell(true(app.numfile, 1), ones(1,app.numfile)), (app.filename)', cell(1, app.numfile)'];
            app.wlmin = min(app.Xdata{1});
            app.wlmax = max(app.Xdata{1});
            for k = 1:app.numfile
                if app.Xdata{k} < app.wlmin
                    app.wlmin = min(app.Xdata{k});
                end
                if app.Xdata{k} > app.wlmax
                    app.wlmax = max(app.Xdata{k});
                end
            end
            app.WavelengthEditField.Limits = [app.wlmin, app.wlmax];
            PltSetUp(app);
        end

        % Button pushed function: ExportPlotButton
        function ExportPlt(app, event)
            tp = str2double(app.UITable.Data(:,3));
            Time = tp(cell2mat(app.UITable.Data(:,1)));
            Real_Data = app.relabs(find(cell2mat(app.UITable.Data(:,1))))';
            Fit_Data = polyval(app.lobf,tp(cell2mat(app.UITable.Data(:,1))));
            ExportData = table(Time, Real_Data, Fit_Data);
            f = 'PhotodegradationData.csv';
            place = fullfile(app.pathname,f);
            writetable(ExportData,place);

            app.ExportLabel.Visible = true;
            app.ExportLabel.Text = strcat('Exported to: ',place);
            pause(5);
            try
                app.ExportLabel.Visible = false;
            catch
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {''; 'Filename'; 'Timepoint (Sec)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @TableCellEdit, true);
            app.LoadDataButton = uibutton(app.Panel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_2);
            xlabel(app.UIAxes, 'Wavelength')
            ylabel(app.UIAxes, 'Absorbance')
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = 'Enter the wavelength of interest. Graph to view change in absorbance at your selected wavelength over time';
            app.Panel_4 = uipanel(app.Panel_2);
            app.LocalMaximumButton = uibutton(app.Panel_4, 'push');
            app.LocalMaximumButton.ButtonPushedFcn = createCallbackFcn(app, @Maximum, true);
            app.LocalMaximumButton.Text = 'Local Maximum';
            app.GraphButton = uibutton(app.Panel_4, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @lnGraph, true);
            app.GraphButton.Text = 'Graph';
            app.WavelengthEditFieldLabel = uilabel(app.Panel_4);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.Panel_4, 'numeric');
            app.WavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @EditField, true);
            app.Panel_3 = uipanel(app.UIFigure);
            app.UIAxes2 = uiaxes(app.Panel_3);
            title(app.UIAxes2, 'ln(Abs / Abs@time=0)')
            xlabel(app.UIAxes2, 'Time (sec)')
            ylabel(app.UIAxes2, 'Absorbance')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.Panel_5 = uipanel(app.Panel_3);
            app.LineofBestFitLabel = uilabel(app.Panel_5);
            app.LineofBestFitLabel.Visible = 'off';
            app.LineofBestFitLabel.Text = 'Line of Best Fit: ';
            app.ExportPlotButton = uibutton(app.Panel_5, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlt, true);
            app.ExportPlotButton.Visible = 'off';
            app.ExportPlotButton.Text = 'Export Plot';
            app.ExportLabel = uilabel(app.Panel_5);
            app.ExportLabel.Visible = 'off';
            app.ExportLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhotodegradationAnalysisapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end